classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ImagePanel               matlab.ui.container.Panel
        AddImageButton           matlab.ui.control.Button
        ImageBox                 matlab.ui.control.UIAxes
        AddImageIconImage        matlab.ui.control.Image
        InstructionText          matlab.ui.control.Label
        GoBackButton             matlab.ui.control.Button
        StepNameText             matlab.ui.control.Label
        TourIntoThePictureLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Image % Image to process
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddImageButton
        function AddImageButtonPushed(app, event)
            [file, location, indx] = uigetfile({'*.jpg;*.png;*.bmp', 'Images (*.jpg, *.png, *.bmp)'});
            
            % So that user can still choose image in fullscreen 
            app.UIFigure.WindowStyle = "normal";

            if isequal(file,0)
               disp('User selected Cancel')
               return
            else
               disp(['User selected ', fullfile(location,file),... 
                     ' and filter index: ', num2str(indx)])
            end

            app.UIFigure.WindowStyle = "alwaysontop";

            app.Image = imread(fullfile(location, file));

            [sz1, sz2] = size(app.Image);
            
            % Image size reduction, faster image processing
            if any(sz1>3000 | sz2>3000)            
                reduce_scal = 3200/max(sz1,sz2);            
                app.Image = imresize(app.Image, reduce_scal,"Antialiasing",false);            
            end

            imshow(app.Image, 'Parent', app.ImageBox);
     
            app.StepNameText.Text = 'Select foreground objects';
            app.InstructionText.Text = 'Select the foreground objects on the left image by drawing a polygon around each one of them';
            app.AddImageButton.Visible = 0;
            app.AddImageIconImage.Visible = 0;
            app.GoBackButton.Visible = 1;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TourIntoThePictureLabel = uilabel(app.UIFigure);
            app.TourIntoThePictureLabel.Text = 'Tour Into The Picture';
            app.StepNameText = uilabel(app.UIFigure);
            app.StepNameText.Text = 'Add picture ';
            app.GoBackButton = uibutton(app.UIFigure, 'push');
            app.GoBackButton.Icon = fullfile(pathToMLAPP, 'icons', 'go-back.png');
            app.GoBackButton.Visible = 'off';
            app.GoBackButton.Text = '';
            app.InstructionText = uilabel(app.UIFigure);
            app.InstructionText.Text = 'Add an image by clicking';
            app.AddImageIconImage = uiimage(app.UIFigure);
            app.AddImageIconImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'select-picture.png');
            app.ImagePanel = uipanel(app.UIFigure);
            app.ImageBox = uiaxes(app.ImagePanel);
            app.ImageBox.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ImageBox.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.ImageBox.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ImageBox.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.ImageBox.Visible = 'off';
            app.AddImageButton = uibutton(app.ImagePanel, 'push');
            app.AddImageButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageButtonPushed, true);
            app.AddImageButton.Icon = fullfile(pathToMLAPP, 'icons', 'select-picture.png');
            app.AddImageButton.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end